classdef Calculate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Comsol                  matlab.ui.Figure
        Label_rateOfProgress_2  matlab.ui.control.Label
        Label_rateOfProgress    matlab.ui.control.Label
        EditField_finish        matlab.ui.control.NumericEditField
        EditField_all           matlab.ui.control.NumericEditField
        Button_stop             matlab.ui.control.Button
        Label_second            matlab.ui.control.Label
        EditField_second        matlab.ui.control.NumericEditField
        Label_minute            matlab.ui.control.Label
        EditField_minute        matlab.ui.control.NumericEditField
        Label_time              matlab.ui.control.Label
        Button_pause            matlab.ui.control.Button
        Button_run              matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Label_cln_crn_in_2      matlab.ui.control.Label
        Button_check            matlab.ui.control.Button
        Button_initialize       matlab.ui.control.Button
        EditField_FilePath      matlab.ui.control.EditField
        DropDown_resultFile     matlab.ui.control.DropDown
        Label_import            matlab.ui.control.Label
        Label_min               matlab.ui.control.Label
        Label_step              matlab.ui.control.Label
        Label_max               matlab.ui.control.Label
        Label_jxh_in            matlab.ui.control.Label
        EditField_jxh_min       matlab.ui.control.NumericEditField
        EditField_jxh_step      matlab.ui.control.NumericEditField
        EditField_jxh_max       matlab.ui.control.NumericEditField
        Label_jxw_in            matlab.ui.control.Label
        EditField_jxw_min       matlab.ui.control.NumericEditField
        EditField_jxw_step      matlab.ui.control.NumericEditField
        EditField_jxw_max       matlab.ui.control.NumericEditField
        Label_cw_in             matlab.ui.control.Label
        EditField_cw_min        matlab.ui.control.NumericEditField
        EditField_cw_step       matlab.ui.control.NumericEditField
        EditField_cw_max        matlab.ui.control.NumericEditField
        Label_cln_crn_in        matlab.ui.control.Label
        EditField_crn_in        matlab.ui.control.NumericEditField
        EditField_cln_in        matlab.ui.control.NumericEditField
        gap                     matlab.ui.control.NumericEditField
        Label_gap               matlab.ui.control.Label
        Button_file_path        matlab.ui.control.Button
        OpenButton              matlab.ui.control.Button
        Tab_2                   matlab.ui.container.Tab
        Label_import_2          matlab.ui.control.Label
        Gauge                   matlab.ui.control.LinearGauge
        EditField_crn_out       matlab.ui.control.NumericEditField
        Label_crn_out           matlab.ui.control.Label
        EditField_cln_out       matlab.ui.control.NumericEditField
        Label_cln_out           matlab.ui.control.Label
        EditField_cw_out        matlab.ui.control.NumericEditField
        Label_cw_out            matlab.ui.control.Label
        EditField_jxw_out       matlab.ui.control.NumericEditField
        Label_jxw_out           matlab.ui.control.Label
        EditField_jxh_out       matlab.ui.control.NumericEditField
        Label_jxh_out           matlab.ui.control.Label
        delta_B                 matlab.ui.control.NumericEditField
        Label_delta_B           matlab.ui.control.Label
        Label_back              matlab.ui.control.Label
        Label_image             matlab.ui.control.Label
        Image_line              matlab.ui.control.Image
        Tab_3                   matlab.ui.container.Tab
        Sheet3Button            matlab.ui.control.StateButton
        Sheet2Button            matlab.ui.control.StateButton
        Sheet1Button            matlab.ui.control.StateButton
        UITable                 matlab.ui.control.Table
        Button_quit             matlab.ui.control.Button
        Image_logo              matlab.ui.control.Image
        Label_lab               matlab.ui.control.Label
    end


    properties (Access = public) %全局变量
        file_path = 'D:\GitHub\paper1'; % 默认文件夹地址
        clearValue = 0; %默认Unselected
        result_path = 'D:\GitHub\paper1\result_default'; % 默认result文件夹地址
        pauseValue = 0; %判断是否暂停
        resultname; %新建文件夹名字变量
        runValue = 0; %0为可以运行run，1为停止运行run
        data1 %储存数据
        data2
        data3
    end

    
    methods (Access = private)
        
        %run按键可用
        function init_run(app)
            app.Button_run.Icon = 'run.jpg';
            app.Button_run.Text = 'run';
            app.Button_run.FontColor = [0 0 0]; %黑色
            app.Button_run.Enable='on'; %可用
        end
        %stop按键不可用
        function init_stop(app)
            app.Button_stop.Text = 'stop';
            app.Button_stop.FontColor = [0 0 0];
            app.Button_stop.Visible='off'; %不显示
            app.Button_stop.Enable='off'; %不可用
        end
        %pause按键不可用
        function init_pause(app)
            app.Button_pause.Text = 'pause';
            app.Button_pause.FontColor = [0 0 0];
            app.Button_pause.Visible='off';
            app.Button_pause.Enable='off';
        end
        
        function init_ran(app)
            %初始化run按钮
            init_run(app); 
            %初始化stop按钮
            init_stop(app);
            %初始化pause按钮
            init_pause(app)
            %初始化
            app.Button_initialize.Enable='on';
            app.Button_file_path.Enable='on';
            app.OpenButton.Enable='on';
            app.Button_quit.Enable='on';
            %初始化结果文件夹value
            app.DropDown_resultFile.Enable='on';
            app.DropDown_resultFile.Value=num2str(0);
            app.EditField_FilePath.Value='当前未选择结果文件夹';
            app.clearValue = 0; %默认Unselected
            app.pauseValue = 0; %判断是否暂停，0为否，1为暂停
            app.resultname=''; %新建文件夹名字变量
            app.runValue = 0; %变量为0时，点击run开始计算，并将runValue变为1；若为1，则终止计算。
            app.EditField_finish.Value = 0;
            app.EditField_all.Value = 0;
        end
        
        function pre_run(app)
            %使pause按钮可用
            app.Button_pause.Visible='on';
            app.Button_pause.Enable='on';
            %使stop按钮可用
            app.Button_stop.Visible='on';
            app.Button_stop.Enable='on';
            %使按钮不可用
            app.Button_initialize.Enable='off';%初始化不可用
            app.Button_file_path.Enable='off';%选择程序文件夹不可用
            app.OpenButton.Enable='off';%打开comsol with MATLAB软件按钮不可用
            app.DropDown_resultFile.Enable='off';%选择结果文件夹不可用
            app.Button_quit.Enable='off';%退出按钮不可用
            app.Button_run.Enable='off';%运行按钮不可用
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %启动后自动运行的函数


        end

        % Button pushed function: Button_initialize
        function Button_initializePushed(app, event)
            %初始化run按钮
            init_run(app); 
            %初始化stop按钮
            init_stop(app);
            %初始化pause按钮
            init_pause(app)
            %初始化
            app.Button_initialize.Enable='on';
            app.Button_file_path.Enable='on';
            app.OpenButton.Enable='on';
            app.Button_quit.Enable='on';
            %初始化结果文件夹value
            app.DropDown_resultFile.Enable='on';
            app.DropDown_resultFile.Value=num2str(0);
            app.EditField_FilePath.Value='当前未选择结果文件夹';
            app.clearValue = 0; %默认Unselected
            app.pauseValue = 0; %判断是否暂停，0为否，1为暂停
            app.resultname=''; %新建文件夹名字变量
            app.runValue = 0; %变量为0时，点击run开始计算，并将runValue变为1；若为1，则终止计算。
            app.EditField_finish.Value = 0;
            app.EditField_all.Value = 0;
        end

        % Button pushed function: Button_file_path
        function Button_file_pathPushed(app, event)
            app.file_path = uigetdir('*.*','请选择文件夹'); %fliepath为文件夹路径
        end

        % Button pushed function: Button_run
        function Button_runPushed(app, event)
            while 1
                clc;%清空命令行状态
                tic;%时间重置
                %break变量 用于终止函数 跳出最外层
                jxwbreak=0;
                jxhbreak=0;
                %检测是否选择结果文件夹
                if app.clearValue==1
                elseif app.clearValue==2
                    [status, message, messageid]=rmdir('result_default', 's');
                    %创建文件夹
                    mkdir result_default\data
                    mkdir result_default\picture
                    mkdir result_default\line
                elseif app.clearValue==3
                    mkdir(app.resultname);
                    mkdir(app.resultname, 'data');
                    mkdir(app.resultname, 'picture');
                    mkdir(app.resultname, 'line');
                else
                    msgbox('请选择结果文件夹','操作错误','error');
                    init_ran(app);
                    break;
                end
                
                data_path = [app.result_path,'\data\'];
                xian_path = [app.result_path,'\Line\'];
                tu_path = [app.result_path,'\picture\'];
                jxh0 = app.EditField_jxh_min.Value;
                jxw0 = app.EditField_jxw_min.Value;
                cw0 = app.EditField_cw_min.Value;
                jxh1 = app.EditField_jxh_max.Value;
                jxw1 = app.EditField_jxw_max.Value;
                cw1 = app.EditField_cw_max.Value;
                s1 = app.EditField_jxh_step.Value;
                s2 = app.EditField_jxw_step.Value;
                s3 = app.EditField_cw_step.Value;
                jx = app.gap.Value;
                clnin = app.EditField_cln_in.Value;
                crnin = app.EditField_crn_in.Value;
                jx = [ num2str(jx),'[mm]'];
                count = 0;
                All = ((jxh1-jxh0)/s1+1)*((jxw1-jxw0)/s2+1)*((cw1-cw0)/s3+1);
                app.EditField_all.Value = All;
                app.data1=zeros(round(All),9);
                %确定是否开始计算
                button=questdlg('确定开始计算吗？','计算程序','Yes','No','Yes');%内容，标题，选项（2个），默认选项
                if strcmp(button,'No')%判断选择
                    msgbox('已取消计算','退出计算','help');
                    init_ran(app);
                    break%否则关闭弹窗，不退出界面
                end
                %正式开始运行
                pre_run(app);%按钮的交互性改变
                
                app.TabGroup.SelectedTab=app.Tab_2;%将选项卡组置为第二个界面
                for jxhn = jxh0 : s1 : jxh1
                    if jxhbreak==1
                        break
                    end
                    for jxwn = jxw0 : s2 : jxw1
                        if jxwbreak==1
                            break
                        end
                        for cwn = cw0:s3:cw1
                            %暂停程序
                            if app.pauseValue==1 
                                f=msgbox('已暂停，点击确定或关闭窗口将继续','提醒','help');
                                uiwait(f);
                                app.pauseValue=0;
                                app.Button_pause.Icon = 'pause.jpg';
                                app.Button_pause.Text = 'pause';
                                app.Button_pause.FontColor = [0 0 0];
                            end
                            %终止程序
                            if app.runValue==1
                                app.Button_stop.Text = 'stopping';
                                app.Button_stop.FontColor = [1 0 0];
                                app.Button_stop.Enable='off';
                                button=questdlg('确定终止吗？','终止计算','Yes','No','Yes');%内容，标题，选项（2个），默认选项
                                if strcmp(button,'Yes')
                                    jxwbreak=1;%跳出jxw循环的识别变量
                                    jxhbreak=1;%跳出jxh循环的识别变量
                                    msgbox('已终止计算','终止计算','help');
                                    break %跳出cw循环
                                else
                                    app.runValue=0;
                                    app.Button_stop.Text = 'stop';
                                    app.Button_stop.FontColor = [0 0 0];
                                    app.Button_stop.Enable='on';
                                end
                            end
                            %开始赋值准备仿真计算
                            wcn = cwn+jxwn;
                            cln = floor(21/wcn);
                            crn = cln;
                            cnz = cln + crn;
                            jxh = [ num2str(jxhn),'[mm]'];
                            jxw = [ num2str(jxwn),'[mm]'];
                            cw = [ num2str(cwn),'[mm]'];
                            wc = [ num2str(wcn),'[mm]'];
                            name_path=['jxh',num2str(jxh),'-','jxw',num2str(jxw),'-','cw',num2str(cw),'-','cln',num2str(cln-1),'-','crn',num2str(crn-1),'-'];
                            %Model(jxh, jxw, cw, jx, wc, cnz, cln, crn, model_path, data_path, xian_path,tu_path);
                            %site=['A',num2str(count+2)];
                            %analyse(jxh, jxw, cw, clnn, data_path, xlsx_path, site);
                            pause(1);
                            app.EditField_jxh_out.Value = jxhn;
                            app.EditField_jxw_out.Value = jxwn;
                            app.EditField_cw_out.Value = cwn;
                            app.EditField_cln_out.Value = cln;
                            app.EditField_crn_out.Value = crn;
                            count = count + 1;
                            app.data1(count,:)=[count,jxhn,jxwn,cwn,0,cln,0,crn,0];
                            %查看表格显示状态按钮
                            if app.Sheet1Button.Value==1
                                app.UITable.Data=app.data1;
                            elseif app.Sheet2Button.Value==1
                                app.UITable.Data=app.data2;
                            elseif app.Sheet3Button.Value==1
                                app.UITable.Data=app.data3;
                            end
                            app.EditField_finish.Value = count;%更新程序已完成的数据
                            app.Gauge.Value = floor(count*100/All);%更新进度条
                            app.EditField_second.Value = floor(rem(toc,60));%更新计算所需时间的秒
                            app.EditField_minute.Value = floor(toc/60);%更新计算所需时间的分
                        end
                    end
                end
                %程序运行完毕或程序终止
                app.TabGroup.SelectedTab=app.Tab_3;%将选项卡组置为第三个界面
                init_ran(app);%运行完毕的初始化
                break;
            end

        end

        % Button pushed function: Button_quit
        function Button_quitPushed(app, event)
            button=questdlg('确定退出吗？','退出程序','Yes','No','Yes');%内容，标题，选项（2个），默认选项
            if strcmp(button,'Yes')
                delete(app.Comsol);%Yes，关闭程序界面
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            open('D:\All Softwares\COMSOL56\Multiphysics\bin\win64\comsolmphserver.exe')
        end

        % Button pushed function: Button_pause
        function Button_pausePushed(app, event)

            app.pauseValue = 1;
            app.Button_pause.Icon = 'run.jpg';
            app.Button_pause.Text = 'go on';
            app.Button_pause.FontColor = [1 0 0];

        end

        % Window key press function: Comsol
        function ComsolWindowKeyPress(app, event)
            %监听键盘
            key = event.Key;
            %考虑使用switch语句进行判断
            switch key
                case "return"

            end
        end

        % Value changed function: DropDown_resultFile
        function DropDown_resultFileValueChanged(app, event)
            app.clearValue = str2double(app.DropDown_resultFile.Value);
            while 1
                if app.clearValue==1
                    judge=uigetdir('*.*','请选择result文件夹');
                    if judge == 0
                        msgbox('请选择结果文件夹','操作错误','error');
                        break
                    else
                        app.result_path=judge;
                    end
                elseif app.clearValue==2
                    app.result_path = [app.file_path,'\result_default'];
                elseif app.clearValue==3
                    app.resultname=char(inputdlg('请输入新文件名'));
                    if isempty(app.resultname) == 1
                        msgbox('请选择结果文件夹','操作错误','error');
                        break
                    else
                        app.result_path = [app.file_path,'\',app.resultname];
                    end
                end
                app.EditField_FilePath.Value=app.result_path;
                break
            end

        end

        % Button pushed function: Button_stop
        function Button_stopPushed(app, event)
            app.runValue=1;%将runValue改为1，run程序中，为1即停止运行
        end

        % Button pushed function: Button_check
        function Button_checkPushed(app, event)
            value=app.EditField_jxh_max.Value;
            assignin('base','value',value)
            whos('base')
        end

        % Value changed function: Sheet1Button
        function Sheet1ButtonValueChanged(app, event)
            app.Sheet1Button.Value=1;
            app.Sheet2Button.Value=0;
            app.Sheet3Button.Value=0;
        end

        % Value changed function: Sheet2Button
        function Sheet2ButtonValueChanged(app, event)
            app.Sheet1Button.Value=0;
            app.Sheet2Button.Value=1;
            app.Sheet3Button.Value=0;
        end

        % Value changed function: Sheet3Button
        function Sheet3ButtonValueChanged(app, event)
            app.Sheet1Button.Value=0;
            app.Sheet2Button.Value=0;
            app.Sheet3Button.Value=1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Comsol = uifigure('Visible', 'off');
            app.Comsol.Name = '基于COMSOL与MATLAB的磁性液体密封磁场参数化仿真软件';
            app.Comsol.Icon = 'comsol.jpg';
            app.Comsol.WindowKeyPressFcn = createCallbackFcn(app, @ComsolWindowKeyPress, true);
            app.Label_lab = uilabel(app.Comsol);
            app.Label_lab.Text = '磁性液体研究所';
            app.Image_logo = uiimage(app.Comsol);
            app.Image_logo.ImageSource = '北京交通大学logo.png';
            app.Button_quit = uibutton(app.Comsol, 'push');
            app.Button_quit.ButtonPushedFcn = createCallbackFcn(app, @Button_quitPushed, true);
            app.Button_quit.Icon = 'open.png';
            app.Button_quit.Text = 'quit';
            app.TabGroup = uitabgroup(app.Comsol);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '输入';
            app.OpenButton = uibutton(app.Tab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = 'comsol.jpg';
            app.OpenButton.Text = 'Open';
            app.Button_file_path = uibutton(app.Tab, 'push');
            app.Button_file_path.ButtonPushedFcn = createCallbackFcn(app, @Button_file_pathPushed, true);
            app.Button_file_path.Icon = 'file.jpg';
            app.Button_file_path.Tooltip = {'默认程序所在文件夹为D:\GitHub\paper1'; '如有改变，请在此选择程序所在根目录'};
            app.Button_file_path.Text = '程序文件夹';
            app.Label_gap = uilabel(app.Tab);
            app.Label_gap.Text = '间隙';
            app.gap = uieditfield(app.Tab, 'numeric');
            app.gap.Value = 0.1;
            app.EditField_cln_in = uieditfield(app.Tab, 'numeric');
            app.EditField_crn_in = uieditfield(app.Tab, 'numeric');
            app.Label_cln_crn_in = uilabel(app.Tab);
            app.Label_cln_crn_in.Text = '左齿数';
            app.EditField_cw_max = uieditfield(app.Tab, 'numeric');
            app.EditField_cw_max.Value = 0.28;
            app.EditField_cw_step = uieditfield(app.Tab, 'numeric');
            app.EditField_cw_step.Value = 0.02;
            app.EditField_cw_min = uieditfield(app.Tab, 'numeric');
            app.EditField_cw_min.Value = 0.12;
            app.Label_cw_in = uilabel(app.Tab);
            app.Label_cw_in.Text = '齿宽';
            app.EditField_jxw_max = uieditfield(app.Tab, 'numeric');
            app.EditField_jxw_max.Value = 1.4;
            app.EditField_jxw_step = uieditfield(app.Tab, 'numeric');
            app.EditField_jxw_step.Value = 0.1;
            app.EditField_jxw_min = uieditfield(app.Tab, 'numeric');
            app.EditField_jxw_min.Value = 0.4;
            app.Label_jxw_in = uilabel(app.Tab);
            app.Label_jxw_in.Text = '槽宽';
            app.EditField_jxh_max = uieditfield(app.Tab, 'numeric');
            app.EditField_jxh_max.Value = 1;
            app.EditField_jxh_step = uieditfield(app.Tab, 'numeric');
            app.EditField_jxh_step.Value = 0.1;
            app.EditField_jxh_min = uieditfield(app.Tab, 'numeric');
            app.EditField_jxh_min.Value = 0.3;
            app.Label_jxh_in = uilabel(app.Tab);
            app.Label_jxh_in.Text = '槽高';
            app.Label_max = uilabel(app.Tab);
            app.Label_max.Text = '最大值';
            app.Label_step = uilabel(app.Tab);
            app.Label_step.Text = '步进';
            app.Label_min = uilabel(app.Tab);
            app.Label_min.Text = '最小值';
            app.Label_import = uilabel(app.Tab);
            app.Label_import.Text = '输入';
            app.DropDown_resultFile = uidropdown(app.Tab);
            app.DropDown_resultFile.Items = {'请选择结果文件夹', '默认文件夹', '选择文件夹', '新建文件夹'};
            app.DropDown_resultFile.ItemsData = {'0', '2', '1', '3'};
            app.DropDown_resultFile.ValueChangedFcn = createCallbackFcn(app, @DropDown_resultFileValueChanged, true);
            app.DropDown_resultFile.Tooltip = {'请选择结果文件夹，若不选择将无法进行计算。'; '选择默认文件夹将会清空默认文件夹后存放数据。你也可以选择已有文件夹，或新建文件夹。'};
            app.DropDown_resultFile.Value = '0';
            app.EditField_FilePath = uieditfield(app.Tab, 'text');
            app.EditField_FilePath.Value = '当前未选择结果文件夹';
            app.Button_initialize = uibutton(app.Tab, 'push');
            app.Button_initialize.ButtonPushedFcn = createCallbackFcn(app, @Button_initializePushed, true);
            app.Button_initialize.Icon = '初始化2.png';
            app.Button_initialize.Tooltip = {'默认程序所在文件夹为D:\GitHub\paper1'; '如有改变，请在此选择程序所在根目录'};
            app.Button_initialize.Text = '初始化';
            app.Button_check = uibutton(app.Tab, 'push');
            app.Button_check.ButtonPushedFcn = createCallbackFcn(app, @Button_checkPushed, true);
            app.Button_check.Icon = '放大镜.png';
            app.Button_check.Tooltip = {''};
            app.Button_check.Text = '检查数据类型';
            app.Label_cln_crn_in_2 = uilabel(app.Tab);
            app.Label_cln_crn_in_2.Text = '右齿数';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '输出';
            app.Image_line = uiimage(app.Tab_2);
            app.Image_line.ImageSource = '测试用图片.png';
            app.Label_image = uilabel(app.Tab_2);
            app.Label_image.Text = '磁场密度模曲线图';
            app.Label_back = uilabel(app.Tab_2);
            app.Label_back.Text = '当前所完成计算的参数';
            app.Label_delta_B = uilabel(app.Tab_2);
            app.Label_delta_B.Text = '总磁通密度模';
            app.delta_B = uieditfield(app.Tab_2, 'numeric');
            app.Label_jxh_out = uilabel(app.Tab_2);
            app.Label_jxh_out.Text = '槽高';
            app.EditField_jxh_out = uieditfield(app.Tab_2, 'numeric');
            app.Label_jxw_out = uilabel(app.Tab_2);
            app.Label_jxw_out.Text = '槽宽';
            app.EditField_jxw_out = uieditfield(app.Tab_2, 'numeric');
            app.Label_cw_out = uilabel(app.Tab_2);
            app.Label_cw_out.Text = '齿宽';
            app.EditField_cw_out = uieditfield(app.Tab_2, 'numeric');
            app.Label_cln_out = uilabel(app.Tab_2);
            app.Label_cln_out.Text = '左齿数';
            app.EditField_cln_out = uieditfield(app.Tab_2, 'numeric');
            app.Label_crn_out = uilabel(app.Tab_2);
            app.Label_crn_out.Text = '右齿数';
            app.EditField_crn_out = uieditfield(app.Tab_2, 'numeric');
            app.Gauge = uigauge(app.Tab_2, 'linear');
            app.Label_import_2 = uilabel(app.Tab_2);
            app.Label_import_2.Text = '进度';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '表格';
            app.UITable = uitable(app.Tab_3);
            app.UITable.ColumnName = {'序号'; '槽高[mm]'; '槽宽[mm]'; '极齿宽[mm]'; '左极齿ΔB[T]'; '左极齿数'; '右极齿ΔB[T]'; '右极齿数'; '总ΔB[T]'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.Sheet1Button = uibutton(app.Tab_3, 'state');
            app.Sheet1Button.ValueChangedFcn = createCallbackFcn(app, @Sheet1ButtonValueChanged, true);
            app.Sheet1Button.Text = 'Sheet1';
            app.Sheet1Button.Value = true;
            app.Sheet2Button = uibutton(app.Tab_3, 'state');
            app.Sheet2Button.ValueChangedFcn = createCallbackFcn(app, @Sheet2ButtonValueChanged, true);
            app.Sheet2Button.Text = 'Sheet2';
            app.Sheet3Button = uibutton(app.Tab_3, 'state');
            app.Sheet3Button.ValueChangedFcn = createCallbackFcn(app, @Sheet3ButtonValueChanged, true);
            app.Sheet3Button.Text = 'Sheet3';
            app.Button_run = uibutton(app.Comsol, 'push');
            app.Button_run.ButtonPushedFcn = createCallbackFcn(app, @Button_runPushed, true);
            app.Button_run.Icon = 'run.jpg';
            app.Button_run.Text = 'run';
            app.Button_pause = uibutton(app.Comsol, 'push');
            app.Button_pause.ButtonPushedFcn = createCallbackFcn(app, @Button_pausePushed, true);
            app.Button_pause.Icon = 'pause.jpg';
            app.Button_pause.Enable = 'off';
            app.Button_pause.Visible = 'off';
            app.Button_pause.Text = 'pause';
            app.Label_time = uilabel(app.Comsol);
            app.Label_time.Text = '已计算';
            app.EditField_minute = uieditfield(app.Comsol, 'numeric');
            app.Label_minute = uilabel(app.Comsol);
            app.Label_minute.Text = '分钟';
            app.EditField_second = uieditfield(app.Comsol, 'numeric');
            app.Label_second = uilabel(app.Comsol);
            app.Label_second.Text = '秒';
            app.Button_stop = uibutton(app.Comsol, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @Button_stopPushed, true);
            app.Button_stop.Icon = 'stop.png';
            app.Button_stop.Enable = 'off';
            app.Button_stop.Visible = 'off';
            app.Button_stop.Text = 'Stop';
            app.EditField_all = uieditfield(app.Comsol, 'numeric');
            app.EditField_finish = uieditfield(app.Comsol, 'numeric');
            app.Label_rateOfProgress = uilabel(app.Comsol);
            app.Label_rateOfProgress.Text = '程序已完成/总数';
            app.Label_rateOfProgress_2 = uilabel(app.Comsol);
            app.Label_rateOfProgress_2.Text = '/';
            % Show the figure after all components are created
            app.Comsol.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Comsol)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Comsol)
        end
    end
end